classdef AllTab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Gioithieu                     matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        NghiemTab                     matlab.ui.container.Tab
        NhaphamlapEditField           matlab.ui.control.EditField
        NhphmlpLabel                  matlab.ui.control.Label
        SolanlapEditField             matlab.ui.control.EditField
        SlnlpEditFieldLabel           matlab.ui.control.Label
        KetquanghiemEditField         matlab.ui.control.EditField
        KtqunghimEditFieldLabel       matlab.ui.control.Label
        ChonPPDropDown                matlab.ui.control.DropDown
        ChnphngphptmDropDownLabel     matlab.ui.control.Label
        SaisoEditField                matlab.ui.control.EditField
        NhpsaischophpEditFieldLabel   matlab.ui.control.Label
        KhoangphanlynghiemEditField   matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        NhapptEditField               matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel     matlab.ui.control.Label
        ChayButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        NoisuyTab                     matlab.ui.container.Tab
        ChaychuongtrinhButton         matlab.ui.control.Button
        Ketquanoisuy                  matlab.ui.control.EditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        Nhapgiatricannoisuy           matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        Ketquadathucnoisuy            matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        ChonPPnoisuy                  matlab.ui.control.DropDown
        ChnphngphpnisuyDropDownLabel  matlab.ui.control.Label
        Nhapy                         matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        Nhapx                         matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        HoiquyTab                     matlab.ui.container.Tab
        runButton                     matlab.ui.control.Button
        KetquaphuongtrinhhoiquyTextArea  matlab.ui.control.TextArea
        KtquphngtrnhhiquyLabel        matlab.ui.control.Label
        NhapyEditField                matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_3      matlab.ui.control.Label
        NhapxEditField                matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_3      matlab.ui.control.Label
        KetquadudoanEditField         matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        NhapgiatridudoanEditField     matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        ChnphngphpDropDownLabel_2     matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        DaohamTab                     matlab.ui.container.Tab
        run1Button                    matlab.ui.control.Button
        Ketqua1EditField              matlab.ui.control.NumericEditField
        KtquLabel                     matlab.ui.control.Label
        NhapgiatricandaohamEditField  matlab.ui.control.NumericEditField
        NhpgitrcntnhohmLabel          matlab.ui.control.Label
        PhuongphapdaohamDropDown      matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel    matlab.ui.control.Label
        SaisoDropDown                 matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel      matlab.ui.control.Label
        NhapbuochEditField            matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        NhaphamsoEditField            matlab.ui.control.EditField
        NhphmsEditFieldLabel_2        matlab.ui.control.Label
        HocLabel                      matlab.ui.control.Label
        Nhapy1EditField               matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_4      matlab.ui.control.Label
        Nhapx1EditField               matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_4      matlab.ui.control.Label
        TchPhnTab                     matlab.ui.container.Tab
        NhphmsEditFieldLabel          matlab.ui.control.Label
        NhpdliuyEditFieldLabel_2      matlab.ui.control.Label
        NhpdliuxEditFieldLabel_2      matlab.ui.control.Label
        NEditField                    matlab.ui.control.NumericEditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        KetquaEditField               matlab.ui.control.EditField
        KtquEditFieldLabel            matlab.ui.control.Label
        CantrenEditField              matlab.ui.control.EditField
        CntrnEditFieldLabel           matlab.ui.control.Label
        CanduoiEditField              matlab.ui.control.EditField
        CndiEditFieldLabel            matlab.ui.control.Label
        PhuongphapDropDown            matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        ChayappButton                 matlab.ui.control.Button
        NhpcntnhtchphnLabel           matlab.ui.control.Label
        FunctionEditField             matlab.ui.control.EditField
        YEditField                    matlab.ui.control.EditField
        XEditField                    matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChayButton
        function ChayButtonPushed(app, event)
            f = app.NhapptEditField.Value;
            Pp = app.ChonPPDropDown.Value;
            KPL = app.KhoangphanlynghiemEditField.Value;
            val = str2num(KPL);
            a = val(1);
            b = val(2);
            ss = app.SaisoEditField.Value;
            SS = str2num(ss);
            fp = app.NhaphamlapEditField.Value;
            
            if isempty(KPL)
                        uialert(app.UIFigure, 'Khoảng phân ly phải là số hợp lệ (ví dụ: [0,5])', 'Lỗi nhập liệu');
                        return;
            end
            if isempty(Pp)
                        uialert(app.UIFigure, 'Nhập phương pháp', 'Lỗi nhập liệu');
                        return;
            end
            if isempty(f)
                        uialert(app.UIFigure, 'Nhập phương trình', 'Lỗi nhập liệu');
                        return;
            end
            if isempty(SS)
                        uialert(app.UIFigure, 'Nhập sai số', 'Lỗi nhập liệu');
                        return;
            end
            if(strcmp(Pp,'Lặp'))
                if isempty(fp)
                      uialert(app.UIFigure, 'Nhập hàm lặp', 'Lỗi nhập liệu');
                      return;
                end
                [nghiem,n] = lap2(f,fp,a,b,SS);
                app.KetquanghiemEditField.Value = num2str(nghiem);
                app.SolanlapEditField.Value = num2str(n);
                fve = str2func(['@(x)', f]);   
                x_plot = linspace(a, b, 500);     
                y_plot = arrayfun(fve, x_plot);
                plot(app.UIAxes, x_plot, y_plot, 'LineWidth', 1.5);
                hold(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                title(app.UIAxes, ['Đồ thị của f(x) = ', f]);
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f(x)');
                grid(app.UIAxes, 'on');
            end
            if(strcmp(Pp,'Chia đôi'))
                [nghiem,n] = chiadoi(f,a,b,SS);
                app.KetquanghiemEditField.Value = num2str(nghiem);
                app.SolanlapEditField.Value = num2str(n);
                fve = str2func(['@(x)', f]);   
                x_plot = linspace(a, b, 500);     
                y_plot = arrayfun(fve, x_plot);
                plot(app.UIAxes, x_plot, y_plot, 'LineWidth', 1.5);
                hold(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                title(app.UIAxes, ['Đồ thị của f(x) = ', f]);
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f(x)');
                grid(app.UIAxes, 'on');
            end
            if(strcmp(Pp,'Newton (tiếp tuyến)'))
                [nghiem,n] = tieptuyen(f,a,b,SS);
                app.KetquanghiemEditField.Value = num2str(nghiem);
                app.SolanlapEditField.Value = num2str(n);
                fve = str2func(['@(x)', f]);   
                x_plot = linspace(a, b, 500);     
                y_plot = arrayfun(fve, x_plot);
                plot(app.UIAxes, x_plot, y_plot, 'LineWidth', 1.5);
                hold(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                title(app.UIAxes, ['Đồ thị của f(x) = ', f]);
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f(x)');
                grid(app.UIAxes, 'on');
            end
         
        end

        % Button pushed function: ChaychuongtrinhButton
        function ChaychuongtrinhButtonPushed(app, event)
            Pp = app.ChonPPnoisuy.Value;
            x_str = app.Nhapx.Value;
            x = str2num(x_str);
            y_str = app.Nhapy.Value;
            y = str2num(y_str);
            x_noisuy = app.Nhapgiatricannoisuy.Value;
            x_ns = str2num(x_noisuy);
             if isempty(x)
                        uialert(app.UIFigure, 'Nhập x', 'Lỗi nhập liệu');
                        return;
            end
            if isempty(y)
                        uialert(app.UIFigure, 'Nhập y', 'Lỗi nhập liệu');
                        return;
            end
            if isempty(Pp)
                        uialert(app.UIFigure, 'Chọn phương pháp nội suy', 'Lỗi nhập liệu');
                        return;
            end
            if isempty(x_ns)
                        uialert(app.UIFigure, 'Nhập giá trị nội suy', 'Lỗi nhập liệu');
                        return;
            end
            if(strcmp(Pp,'Lagrange'))
                p_value = Lagrange(x,y,x_ns);
                p_sym = Lagrangesyms(x,y);
                app.Ketquanoisuy.Value = num2str(p_value);
                app.Ketquadathucnoisuy.Value = char(p_sym);
                hold(app.UIAxes2, 'on');

               
                plot(app.UIAxes2, x, y, 'o', 'MarkerSize', 8, 'LineWidth', 2);
        
                
                xx = linspace(min(x), max(x), 300);
                yy = double(subs(p_sym, xx));
        
                
                plot(app.UIAxes2, xx, yy, 'LineWidth', 2);
        
                
                plot(app.UIAxes2, x_ns, p_value, 'r*', 'MarkerSize', 10, 'LineWidth', 2);
        
               
                title(app.UIAxes2, 'Đồ thị nội suy Lagrange');
                xlabel(app.UIAxes2, 'x');
                ylabel(app.UIAxes2, 'y');
                grid(app.UIAxes2, 'on');
        
                hold(app.UIAxes2, 'off');
            end
            if(strcmp(Pp,'Newton'))
                p_value = NewtonInterpolation(x,y,x_ns);
                p_sym = NewtonSymbolic(x,y);
                app.Ketquanoisuy.Value = num2str(p_value);
                app.Ketquadathucnoisuy.Value = char(p_sym);
                hold(app.UIAxes2, 'on');

               
                plot(app.UIAxes2, x, y, 'o', 'MarkerSize', 8, 'LineWidth', 2);
        
                
                xx = linspace(min(x), max(x), 300);
                yy = double(subs(p_sym, xx));
        
                
                plot(app.UIAxes2, xx, yy, 'LineWidth', 2);
        
                
                plot(app.UIAxes2, x_ns, p_value, 'r*', 'MarkerSize', 10, 'LineWidth', 2);
        
               
                title(app.UIAxes2, 'Đồ thị nội suy Lagrange');
                xlabel(app.UIAxes2, 'x');
                ylabel(app.UIAxes2, 'y');
                grid(app.UIAxes2, 'on');
        
                hold(app.UIAxes2, 'off');
            end
                
        end

        % Button pushed function: ChayappButton
        function ChayappButtonPushed(app, event)
            method = app.PhuongphapDropDown.Value; 
            strFunc = app.FunctionEditField.Value;   
            strX = app.XEditField.Value;          
            strY = app.YEditField.Value;         
            
            result = 0;
            
            try 
                % --- XỬ LÝ ĐẦU VÀO ---
                if ~isempty(strFunc)
                    % Trường hợp nhập hàm số
                    a = str2num(app.CanduoiEditField.Value); 
                    b = str2num(app.CantrenEditField.Value);

                    if isempty(a) || isempty(b)
                        uialert(app.UIFigure, 'Cận a, b phải là số hợp lệ (ví dụ: 0, pi, 1.5)', 'Lỗi nhập liệu');
                        return;
                    end
                    N = app.NEditField.Value;   
                    
                    f = str2func(['@(x) ' strFunc]);
                    h = (b - a) / N;
                    x = a:h:b;
                    y = f(x);
                    
                elseif ~isempty(strX) && ~isempty(strY)
                    % Trường hợp nhập dữ liệu mảng
                    x = str2num(strX); 
                    y = str2num(strY); 
                    
                    if length(x) ~= length(y)
                        uialert(app.UIFigure, 'Kích thước x và y phải bằng nhau', 'Lỗi');
                        return;
                    end
                    if length(x) < 2
                        uialert(app.UIFigure, 'Cần ít nhất 2 điểm dữ liệu', 'Lỗi');
                        return;
                    end

                    h = x(2) - x(1);
                end
                
                % --- TÍNH TOÁN ---
                switch method
                    case 'Hình thang'
                        result = TichPhanHinhThang(y,h);
                        
                    case 'Simpson 1/3'
                        result = TichPhanSimpson13(y, h);
                        
                    case 'Simpson 3/8'
                        result = TichPhanSimpson38(y, h);
                end 
                
                app.KetquaEditField.Value = num2str(result);
                
            catch ME
                uialert(app.UIFigure, ['Có lỗi xảy ra: ' ME.message], 'Lỗi');
            end 
        end

        % Callback function
        function NhapgiatridudoanEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
        x = str2num(app.NhapxEditField.Value);
        y = str2num(app.NhapyEditField.Value);
switch app.ChonphuongphapDropDown.Value
   
    %   HỒI QUY TUYẾN TÍNH
    case 'Tuyến tính'
        [a0, a1, r2] = HoiQuyTuyenTinh(x, y);
        fx = a1*x + a0;

        % Hiển thị phương trình
        app.KetquaphuongtrinhhoiquyTextArea.Value = sprintf('y = %.4f x + %.4f\nR^2 = %.4f', a1, a0, r2);

        % Vẽ
        plot(app.UIAxes3, x, y, 'o'); hold(app.UIAxes3, 'on');
        plot(app.UIAxes3, x, fx, 'LineWidth', 2);
        hold(app.UIAxes3, 'off');
        title(app.UIAxes3, 'Phương trình hồi quy tuyến tính');

        % Dự đoán
        xp = app.NhapgiatridudoanEditField.Value;
        yp = a1*xp + a0;
        app.KetquadudoanEditField.Value = yp;

    %   HỒI QUY HÀM MŨ: y = a*x^b
    case 'Hàm mũ'
        X = log10(x);
        Y = log10(y);
        [A0, A1, r2] = HoiQuyTuyenTinh(X, Y);

        a0 = 10^A0;
        b  = A1;
        fx = a0 * x.^b;

        % Hiển thị phương trình
        app.KetquaphuongtrinhhoiquyTextArea.Value = sprintf('y = %.4f * x^{%.4f}\nR^2 = %.4f', a0, b, r2);

        % Vẽ
        plot(app.UIAxes3, x, y, 'o'); hold(app.UIAxes3, 'on');
        plot(app.UIAxes3, x, fx, 'LineWidth', 2);
        hold(app.UIAxes3, 'off');
        title(app.UIAxes3, 'Phương trình hồi quy hàm mũ');

        % Dự đoán
        xp = app.NhapgiatridudoanEditField.Value;
        yp = a0 * (xp^b);
        app.KetquadudoanEditField.Value = yp;

    %   HỒI QUY MŨ E: y = a*e^(b*x)
    case 'Hàm mũ e'
        X = x;
        Y = log(y);

        [A0, A1, r2] = HoiQuyTuyenTinh(X, Y);

        a0 = exp(A0);
        b  = A1;
        fx = a0 * exp(b * x);

        % Hiển thị phương trình
        app.KetquaphuongtrinhhoiquyTextArea.Value = sprintf('y = %.4f * e^{%.4f x}\nR^2 = %.4f', a0, b, r2);

        % Vẽ
        plot(app.UIAxes3, x, y, 'o'); hold(app.UIAxes3, 'on');
        plot(app.UIAxes3, x, fx, 'LineWidth', 2);
        hold(app.UIAxes3, 'off');
        title(app.UIAxes3, 'Phương trình hồi quy hàm mũ e');
        xlabel(app.UIAxes3, 'x');
        ylabel(app.UIAxes3, 'y');

        % Dự đoán
        xp = app.NhapgiatridudoanEditField.Value;
        yp = a0 * exp(b * xp);
        app.KetquadudoanEditField.Value = yp;
end

        end

        % Button pushed function: run1Button
        function run1ButtonPushed(app, event)
       % Lấy thông tin chung
            method = app.PhuongphapdaohamDropDown.Value; 
            order  = app.SaisoDropDown.Value;

        % Kiểm tra xem người dùng nhập hàm hay dữ liệu
            f_str = strtrim(app.NhaphamsoEditField.Value);
            if isempty(f_str)
                % ---- Dạng dữ liệu x y ----
                
                x = str2num(app.Nhapx1EditField.Value);
                y = str2num(app.Nhapy1EditField.Value);
                x0 = app.NhapgiatricandaohamEditField.Value;
               
                df = DaoHamXY(x, y, x0, method, order);
                
            else
                % ---- Dạng hàm ----
                f  = str2func(['@(x)' f_str]);
                x0 = app.NhapgiatricandaohamEditField.Value;
                h  = app.NhapbuochEditField.Value;
                if isempty(f)
                    uialert(app.UIFigure, 'Nhập hàm số', 'Lỗi');
                    return;

                end
               
                df = DaoHam(f, x0, h, method, order);
            end

                app.Ketqua1EditField.Value = df;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Gioithieu = uitab(app.TabGroup);
            app.Gioithieu.Title = 'Giới thiệu nhóm';
            app.TextArea = uitextarea(app.Gioithieu);
            app.TextArea.Value = {'- Giới thiệu thành viên'; 'Phạm Tuấn Khanh MSSV: 23207025'; 'Phạm Minh Thắng MSSV: 23207027'; 'Nguyễn Lê Gia Khiêm MSSV: 23207007'; 'Nguyễn Phước Đăng Minh MSSV: 23207011'; '- Nhiệm vụ:'; 'Thiết kế và code tab Nghiệm: Phạm Tuấn Khanh'; 'Thiết kế và code tab Nội suy: Phạm Tuấn Khanh'; 'Thiết kế và code tab Hồi quy : Phạm Minh Thắng'; 'Thiết kế và code tab Đạo hàm : Phạm Minh Thắng'; 'Thiết kế và code tab Tích phân : Nguyễn Lê Gia Khiêm'; 'Làm báo cáo Word : Nguyễn Phước Đăng Minh và Nguyễn Lê Gia Khiêm'; ''; ''};
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChayButton = uibutton(app.NghiemTab, 'push');
            app.ChayButton.ButtonPushedFcn = createCallbackFcn(app, @ChayButtonPushed, true);
            app.ChayButton.Text = 'Chạy';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapptEditField = uieditfield(app.NghiemTab, 'text');
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.KhoangphanlynghiemEditField = uieditfield(app.NghiemTab, 'text');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.SaisoEditField = uieditfield(app.NghiemTab, 'text');
            app.ChnphngphptmDropDownLabel = uilabel(app.NghiemTab);
            app.ChnphngphptmDropDownLabel.Text = 'Chọn phương pháp tìm';
            app.ChonPPDropDown = uidropdown(app.NghiemTab);
            app.ChonPPDropDown.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ChonPPDropDown.Value = 'Chia đôi';
            app.KtqunghimEditFieldLabel = uilabel(app.NghiemTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KetquanghiemEditField = uieditfield(app.NghiemTab, 'text');
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SolanlapEditField = uieditfield(app.NghiemTab, 'text');
            app.NhphmlpLabel = uilabel(app.NghiemTab);
            app.NhphmlpLabel.Text = 'Nhập hàm lặp (cho phương pháp lặp)';
            app.NhaphamlapEditField = uieditfield(app.NghiemTab, 'text');
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NoisuyTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.Nhapx = uieditfield(app.NoisuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Nhapy = uieditfield(app.NoisuyTab, 'text');
            app.ChnphngphpnisuyDropDownLabel = uilabel(app.NoisuyTab);
            app.ChnphngphpnisuyDropDownLabel.Text = 'Chọn phương pháp nội suy';
            app.ChonPPnoisuy = uidropdown(app.NoisuyTab);
            app.ChonPPnoisuy.Items = {'Newton', 'Lagrange'};
            app.ChonPPnoisuy.Value = 'Newton';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.Ketquadathucnoisuy = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.Nhapgiatricannoisuy = uieditfield(app.NoisuyTab, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.NoisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.Ketquanoisuy = uieditfield(app.NoisuyTab, 'text');
            app.ChaychuongtrinhButton = uibutton(app.NoisuyTab, 'push');
            app.ChaychuongtrinhButton.ButtonPushedFcn = createCallbackFcn(app, @ChaychuongtrinhButtonPushed, true);
            app.ChaychuongtrinhButton.Text = 'Chạy chương trình';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi quy';
            app.UIAxes3 = uiaxes(app.HoiquyTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.ChnphngphpDropDownLabel_2 = uilabel(app.HoiquyTab);
            app.ChnphngphpDropDownLabel_2.Text = 'Chọn phương pháp';
            app.ChonphuongphapDropDown = uidropdown(app.HoiquyTab);
            app.ChonphuongphapDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Hàm mũ e'};
            app.ChonphuongphapDropDown.Value = 'Tuyến tính';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HoiquyTab);
            app.NhpgitrcndonEditFieldLabel.Text = {'Nhập giá trị cần'; 'dự đoán'};
            app.NhapgiatridudoanEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HoiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KetquadudoanEditField = uieditfield(app.HoiquyTab, 'numeric');
            app.NhpdliuxEditFieldLabel_3 = uilabel(app.HoiquyTab);
            app.NhpdliuxEditFieldLabel_3.Text = 'Nhập dữ liệu x';
            app.NhapxEditField = uieditfield(app.HoiquyTab, 'text');
            app.NhpdliuyEditFieldLabel_3 = uilabel(app.HoiquyTab);
            app.NhpdliuyEditFieldLabel_3.Text = 'Nhập dữ liệu y';
            app.NhapyEditField = uieditfield(app.HoiquyTab, 'text');
            app.KtquphngtrnhhiquyLabel = uilabel(app.HoiquyTab);
            app.KtquphngtrnhhiquyLabel.Text = {'Kết quả phương trình'; ' hồi quy'};
            app.KetquaphuongtrinhhoiquyTextArea = uitextarea(app.HoiquyTab);
            app.runButton = uibutton(app.HoiquyTab, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'chạy chương trình';
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo hàm';
            app.NhpdliuxEditFieldLabel_4 = uilabel(app.DaohamTab);
            app.NhpdliuxEditFieldLabel_4.Text = 'Nhập dữ liệu x';
            app.Nhapx1EditField = uieditfield(app.DaohamTab, 'text');
            app.NhpdliuyEditFieldLabel_4 = uilabel(app.DaohamTab);
            app.NhpdliuyEditFieldLabel_4.Text = 'Nhập dữ liệu y';
            app.Nhapy1EditField = uieditfield(app.DaohamTab, 'text');
            app.HocLabel = uilabel(app.DaohamTab);
            app.HocLabel.Text = 'Hoặc';
            app.NhphmsEditFieldLabel_2 = uilabel(app.DaohamTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.NhaphamsoEditField = uieditfield(app.DaohamTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.NhapbuochEditField = uieditfield(app.DaohamTab, 'numeric');
            app.ChngitrsaisDropDownLabel = uilabel(app.DaohamTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.SaisoDropDown = uidropdown(app.DaohamTab);
            app.SaisoDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaisoDropDown.Value = 'O(h)';
            app.ChnphngphpohmDropDownLabel = uilabel(app.DaohamTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm ';
            app.PhuongphapdaohamDropDown = uidropdown(app.DaohamTab);
            app.PhuongphapdaohamDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.PhuongphapdaohamDropDown.Value = 'Xấp xỉ tiến';
            app.NhpgitrcntnhohmLabel = uilabel(app.DaohamTab);
            app.NhpgitrcntnhohmLabel.Text = 'Nhập giá trị cần tính đạo hàm ';
            app.NhapgiatricandaohamEditField = uieditfield(app.DaohamTab, 'numeric');
            app.KtquLabel = uilabel(app.DaohamTab);
            app.KtquLabel.Text = 'Kết quả';
            app.Ketqua1EditField = uieditfield(app.DaohamTab, 'numeric');
            app.run1Button = uibutton(app.DaohamTab, 'push');
            app.run1Button.ButtonPushedFcn = createCallbackFcn(app, @run1ButtonPushed, true);
            app.run1Button.Text = 'Chạy chương trình';
            app.TchPhnTab = uitab(app.TabGroup);
            app.TchPhnTab.Title = 'Tích Phân';
            app.XEditField = uieditfield(app.TchPhnTab, 'text');
            app.YEditField = uieditfield(app.TchPhnTab, 'text');
            app.FunctionEditField = uieditfield(app.TchPhnTab, 'text');
            app.FunctionEditField.Value = 'x.^3.*sin(x)';
            app.NhpcntnhtchphnLabel = uilabel(app.TchPhnTab);
            app.NhpcntnhtchphnLabel.Text = 'Nhập cận để tính tích phân ';
            app.ChayappButton = uibutton(app.TchPhnTab, 'push');
            app.ChayappButton.ButtonPushedFcn = createCallbackFcn(app, @ChayappButtonPushed, true);
            app.ChayappButton.Text = 'Chạy app';
            app.ChnphngphpDropDownLabel = uilabel(app.TchPhnTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.PhuongphapDropDown = uidropdown(app.TchPhnTab);
            app.PhuongphapDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhuongphapDropDown.Value = 'Hình thang';
            app.CndiEditFieldLabel = uilabel(app.TchPhnTab);
            app.CndiEditFieldLabel.Text = 'Cận dưới';
            app.CanduoiEditField = uieditfield(app.TchPhnTab, 'text');
            app.CanduoiEditField.Value = '0';
            app.CntrnEditFieldLabel = uilabel(app.TchPhnTab);
            app.CntrnEditFieldLabel.Text = 'Cận trên';
            app.CantrenEditField = uieditfield(app.TchPhnTab, 'text');
            app.CantrenEditField.Value = '1';
            app.KtquEditFieldLabel = uilabel(app.TchPhnTab);
            app.KtquEditFieldLabel.Text = 'Kết quả ';
            app.KetquaEditField = uieditfield(app.TchPhnTab, 'text');
            app.NhpNEditFieldLabel = uilabel(app.TchPhnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.NEditField = uieditfield(app.TchPhnTab, 'numeric');
            app.NEditField.Value = 10;
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.TchPhnTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.TchPhnTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.NhphmsEditFieldLabel = uilabel(app.TchPhnTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AllTab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end